classdef LVapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BrowseButton      matlab.ui.control.Button
        SegmentButton     matlab.ui.control.Button
        imgOriginal       matlab.ui.control.UIAxes
        imgb              matlab.ui.control.UIAxes
        imgc              matlab.ui.control.UIAxes
        imge              matlab.ui.control.UIAxes
        imgd              matlab.ui.control.UIAxes
        btnLeft           matlab.ui.control.Button
        btnRight          matlab.ui.control.Button
        imgg              matlab.ui.control.UIAxes
        imgf              matlab.ui.control.UIAxes
        imgh              matlab.ui.control.UIAxes
        imgi              matlab.ui.control.UIAxes
        DicesimilarityindexFCMTextAreaLabel  matlab.ui.control.Label
        txtDiceFCM        matlab.ui.control.TextArea
        DicesimilarityindexCSFCMTextAreaLabel  matlab.ui.control.Label
        txtDiceCSFCM      matlab.ui.control.TextArea
        imgGT             matlab.ui.control.UIAxes
        btnBrowseGT       matlab.ui.control.Button
        btnAccuracy       matlab.ui.control.Button
        btnLeftGT         matlab.ui.control.Button
        btnRightGT        matlab.ui.control.Button
        lblGTSlice        matlab.ui.control.Label
        lblOriginalSlice  matlab.ui.control.Label
        btnTune           matlab.ui.control.Button
        lblCircularity    matlab.ui.control.Label
        lblArea_2         matlab.ui.control.Label
    end

    
    properties (Access = private)
        V
        Mask
        imageIndex=2; % Description
        maskIndex=2;
        Property5 % Description
        SegmentedFCM
        SegmentedCSFCM
        Property8 % Description
        Circularity_Tol = 0.5
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.imgOriginal)
disableDefaultInteractivity(app.imgb)
disableDefaultInteractivity(app.imgc)
disableDefaultInteractivity(app.imgd)
disableDefaultInteractivity(app.imge)
disableDefaultInteractivity(app.imgf)
disableDefaultInteractivity(app.imgg)
disableDefaultInteractivity(app.imgh)
disableDefaultInteractivity(app.imgi)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            global gg gc;
             
h=guidata(gcbo);
% set(h.message_text,'String','');  % Clear Messages
gg=gg+2;
h.image_filename=1;
h.image_pathname=1;
[filename, pathname] = uigetfile({'*.nii.gz'},'Pick a file'); % Load Image file and path names
    if filename~=0
        gg=1;
        h.gg=1;
        h.image_filename=filename;  % Image file name
        h.image_pathname=pathname;  % Image path name
%         set(h.Showaxes,'visible','off');
%         set(h.figure1_title,'Visible','on');
%         image_1=imread([pathname filename]); % Load Image
app.imageIndex=2;
app.V = niftiread([pathname filename]);
if(app.imageIndex > 0)
 if(app.imageIndex <= 10)
     c = app.V(:,:,app.imageIndex);
 end
end
J = imrotate(c,-90,'bilinear','crop');
I = flip(J ,2);
imshow(I,[],'Parent',app.imgOriginal);
app.lblOriginalSlice.Text= num2str(app.imageIndex);
app.Circularity_Tol = 0.5;
  end
        end

        % Button pushed function: btnLeft
        function btnLeftButtonPushed(app, event)
            try
            if(app.imageIndex > 1)
                app.imageIndex = app.imageIndex-1;
            end
            c = app.V(:,:,app.imageIndex);
                    J = imrotate(c,-90,'bilinear','crop');
                    I = flip(J ,2);
                    imshow(I,[],'Parent',app.imgOriginal);
           app.lblOriginalSlice.Text= num2str(app.imageIndex);
            catch
             warning('Problem using function.  Assigning a value of 0.');
            end
            
        end

        % Button pushed function: btnRight
        function btnRightButtonPushed(app, event)
             if(app.imageIndex < 10)
                app.imageIndex = app.imageIndex+1;
            end
            c = app.V(:,:,app.imageIndex);
                    J = imrotate(c,-90,'bilinear','crop');
                    I = flip(J ,2);
                    imshow(I,[],'Parent',app.imgOriginal);
                    app.lblOriginalSlice.Text= num2str(app.imageIndex);
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            cla(app.imgb);
            cla(app.imgc);
            cla(app.imgd);
            cla(app.imge);
            cla(app.imgf);
            cla(app.imgg);
            cla(app.imgh);
            cla(app.imgi);
            app.lblCircularity.Text="0.7";
               c = app.V(:,:,app.imageIndex);
               J = imrotate(c,-90,'bilinear','crop');
               I = flip(J ,2);
               
               %
               n_clusters = 2;
imData= reshape(I,[],1);
imData= double(imData);

[centers,U,obj]= fcm(imData,n_clusters);

c1 = U(1,:);

c2= U(2,:);

im1 = reshape(c1,size(I));
im2 = reshape(c2,size(I));
im3 = im2bw(I, graythresh(I));
im4 = imfill(im3, 'holes');

%subplot(2,2,1),imshow(im1),title('Cluster 1');
%subplot(2,2,2),imshow(im2),title('Cluster 2'); 
%subplot(2,2,3),imshow(im3),title('Cluster 3');
%subplot(2,2,4),imshow(im4),title('Cluster 4'); 

originalImage = im2; 
binaryImage= im4;
captionFontSize = 14;

% Label each blob so we can make measurements of it
labeledImage = bwlabel(im4, 8); 

% Let's assign each blob a different color to visually show the user the distinct blobs.
coloredLabels = label2rgb (labeledImage, 'hsv', 'k', 'shuffle'); % pseudo random color labels
% coloredLabels is an RGB image.  We could have applied a colormap instead (but only with R2014b and later)

% (b) Partitioned Regions
 %figure;
imshow(binaryImage,[],'Parent',app.imgb); 
% Get a


blobMeasurements = regionprops(labeledImage, originalImage, 'all');
numberOfBlobs = size(blobMeasurements, 1);
% bwboundaries() returns a cell array, where each cell contains the row/column coordinates for an object in the image.
% Plot the borders of all the coins on the original grayscale image using the coordinates returned by bwboundaries.

%title('(c) Delineated Contours', 'FontSize', captionFontSize); 
%axis image; % Make sure image is not artificially stretched because of screen's aspect ratio.
%hold on;
hold(app.imgc, 'on');
 %figure;
imshow(I,[],'Parent',app.imgc); 
boundaries = bwboundaries(binaryImage);
numberOfBoundaries = size(boundaries, 1);
for k = 1 : numberOfBoundaries
	thisBoundary = boundaries{k};
	plot(app.imgc,thisBoundary(:,2), thisBoundary(:,1),"Color",'g',"LineWidth",1);
end
hold(app.imgc, 'off');
%
%
%
blobECD = zeros(1, numberOfBlobs);

% Loop over all blobs printing their measurements to the command window.
area=zeros(numberOfBlobs,1);%Global variable to use for later 
centroid=zeros(numberOfBlobs,2);
circularity=zeros(numberOfBlobs,1);
blobBox=zeros(numberOfBlobs,4);

for k = 1 : numberOfBlobs           % Loop through all blobs.
	% Find the mean of each blob.  (R2008a has a better way where you can pass the original image
	% directly into regionprops.  The way below works for all versions including earlier versions.)
	thisBlobsPixels = blobMeasurements(k).PixelIdxList;  % Get list of pixels in current blob.
	meanGL = mean(originalImage(thisBlobsPixels)); % Find mean intensity (in original image!)
	meanGL2008a = blobMeasurements(k).MeanIntensity; % Mean again, but only for version >= R2008a
	
	blobArea = blobMeasurements(k).Area;		% Get area.
	blobPerimeter = blobMeasurements(k).Perimeter;		% Get perimeter.
	blobCentroid = blobMeasurements(k).Centroid;		% Get centroid one at a time

	blobECD(k) = sqrt(4 * blobArea / pi);					% Compute ECD - Equivalent Circular Diameter.
    % For Step 2.1c
    area(k) = blobArea;
    centroid(k,:) = blobCentroid;
    circularity(k)=(4*pi*(blobArea/blobPerimeter^2));
    blobBox(k,:) = blobMeasurements(k).BoundingBox;
end

% Step 2.1d
Area_Tol= 374;
Round_Tol= 0.7;
Dist_Tol=  27;
% Plot the borders of all the coins on the original grayscale image using the coordinates returned by bwboundaries.
 %figure;
imshow(I,[],'Parent',app.imgd); 

hold(app.imgd, 'on');
boundaries = bwboundaries(binaryImage);
numberOfBoundaries = size(boundaries, 1);
for k = 1 : numberOfBoundaries
    if (area(k)>=Area_Tol)
        thisBoundary = boundaries{k};
        plot(app.imgd,thisBoundary(:,2), thisBoundary(:,1), 'g', 'LineWidth', 1);
    end
end
hold(app.imgd, 'off');
% Step 2.1e 
% Plot the borders of all the coins on the original grayscale image using the coordinates returned by bwboundaries.

imshow(I,[],'Parent',app.imge); 

hold(app.imge, 'on');
boundaries = bwboundaries(binaryImage);
numberOfBoundaries = size(boundaries, 1);
numberOfRemainingRegions=0;
for k = 1 : numberOfBoundaries
    if (area(k)>=Area_Tol)
        if(circularity(k) >=Round_Tol)
            thisBoundary = boundaries{k};
            plot(app.imge,thisBoundary(:,2), thisBoundary(:,1), 'g', 'LineWidth', 1);
            plot(app.imge,centroid(k,1),centroid(k,2),'b*')
            numberOfRemainingRegions = numberOfRemainingRegions+1;
        end
    end
end
hold(app.imge, 'off');

% Step 2.1f 
indexOfRemainingRegions=zeros(numberOfRemainingRegions);
i=1;
for k = 1 : numberOfBoundaries % get the index Of Remaining Regions
 if (area(k)>=Area_Tol)
        if(circularity(k) >=Round_Tol)
            indexOfRemainingRegions(i)=k;
            i=i+1;
        end
 end
end
xyCentroid =0;
for k = 1 : i-1
    xyCentroid(k,1) = centroid(indexOfRemainingRegions(k,1),1);
    xyCentroid(k,2) = centroid(indexOfRemainingRegions(k,1),2);
end
HighestDistanceIndex=0;
% Calculate the distance from each region centroid to the image borders
if(xyCentroid ~=0)
    xyImageEdge = size(I);
     distancesR = pdist2(xyCentroid, xyImageEdge);
     distancesL = pdist2(xyCentroid, [xyImageEdge(1,1),1]);
     distances = distancesR+distancesL;
     % Choose the farthest region from the image border 
     HighestDistance = max(distances);
    for k = 1 : i-1
      if(HighestDistance == distances(k))
          HighestDistanceIndex=k;
      end
    end
    % Plot the borders of all the coins on the original grayscale image using the coordinates returned by bwboundaries.

   %  figure;
imshow(I,[],'Parent',app.imgf); 
hold(app.imgf, 'on');
    boundaries = bwboundaries(binaryImage);
    numberOfBoundaries = size(boundaries, 1);
    numberOfRemainingRegions=0;
    for k = 1 : numberOfBoundaries
        if (indexOfRemainingRegions(HighestDistanceIndex)==k)
                thisBoundary = boundaries{k};
                plot(app.imgf,thisBoundary(:,2), thisBoundary(:,1), 'g', 'LineWidth', 1);
                plot(app.imgf,centroid(k,1),centroid(k,2),'b*')
                numberOfRemainingRegions = numberOfRemainingRegions+1;
        end
    end
hold(app.imgf, 'off');
end 
stepg=0;
% Step 2.1g
% figure;
imshow(I,[],'Parent',app.imgg); 
hold(app.imgg, 'on');
    boundaries = bwboundaries(binaryImage);
    [labeledImg, numberOfRegions] = bwlabel(binaryImage);
    numberOfBoundaries = size(boundaries, 1);
    for k = 1 : numberOfBoundaries
        if(HighestDistanceIndex>0)
        if (indexOfRemainingRegions(HighestDistanceIndex)==k)
                thisRegion = ismember(labeledImg, k); % Extract k'th region alone.
                stepg=1;
                plot(app.imgg,centroid(k,1),centroid(k,2),'g*')
                rectangle(app.imgg,'Position',[blobBox(k,1),blobBox(k,2),blobBox(k,3),blobBox(k,4)],'EdgeColor','g','LineWidth',1)
                rectangle(app.imgg,'Position',[blobBox(k,1)-10,blobBox(k,2)-10,blobBox(k,3)+20,blobBox(k,4)+20],'EdgeColor','r','LineWidth',1)
        end
        end
    end
hold(app.imgg, 'off');

% Segmentation 
if(stepg==1)
 imshow(thisRegion,[],'Parent',app.imgh); 
 %imwrite(thisRegion,'segmented_FCM.png')
 app.SegmentedFCM = thisRegion;
 % CS-FCM 

windowSize=4;  % Decide as per your requirements
kernel=ones(windowSize)/windowSize^2;
result=conv2(single(thisRegion),kernel,'same');
result=result>0.5;
thisRegion(~result)=0; 

 imshow(thisRegion,[],'Parent',app.imgi); 
 app.SegmentedCSFCM = thisRegion;

 
end
        end

        % Button pushed function: btnBrowseGT
        function btnBrowseGTButtonPushed(app, event)
              global gg gc;
             
h=guidata(gcbo);
% set(h.message_text,'String','');  % Clear Messages
gg=gg+2;
h.image_filename=1;
h.image_pathname=1;
[filename, pathname] = uigetfile({'*.nii.gz'},'Pick a file'); % Load Image file and path names
    if filename~=0
        gg=1;
        h.gg=1;
        h.image_filename=filename;  % Image file name
        h.image_pathname=pathname;  % Image path name
%         set(h.Showaxes,'visible','off');
%         set(h.figure1_title,'Visible','on');
%         image_1=imread([pathname filename]); % Load Image
app.maskIndex=app.imageIndex;
app.Mask = niftiread([pathname filename]);
if(app.maskIndex > 0)
 if(app.maskIndex <= 10)
     c = app.Mask(:,:,app.maskIndex);
 end
end
J = imrotate(c,-90,'bilinear','crop');
I = flip(J ,2);
%


%histogram(app.imgGT,I,"Interruptible","on");

bw = I == 3;
imshow(bw,[],'Parent',app.imgGT);

  end
        end

        % Button pushed function: btnLeftGT
        function btnLeftGTButtonPushed(app, event)
                   try
            if(app.maskIndex > 1)
                app.maskIndex = app.maskIndex-1;
            end
            c = app.Mask(:,:,app.maskIndex);
                    J = imrotate(c,-90,'bilinear','crop');
                    I = flip(J ,2);
                    bw = I == 3;
                    imshow(bw,[],'Parent',app.imgGT);
                    app.lblGTSlice.Text= num2str(app.maskIndex);
            catch
             warning('Problem using function.  Assigning a value of 0.');
            end
        end

        % Button pushed function: btnRightGT
        function btnRightGTButtonPushed(app, event)
             try
            if(app.maskIndex < 10)
                app.maskIndex = app.maskIndex+1;
            end
            c = app.Mask(:,:,app.maskIndex);
                    J = imrotate(c,-90,'bilinear','crop');
                    I = flip(J ,2);
                    bw = I == 3;
                    imshow(bw,[],'Parent',app.imgGT);
           app.lblGTSlice.Text= num2str(app.maskIndex);
            catch
             warning('Problem using function.  Assigning a value of 0.');
            end
        end

        % Button pushed function: btnAccuracy
        function btnAccuracyButtonPushed(app, event)
             % Dice FCM
  grndTruth = app.Mask(:,:,app.maskIndex);
  grndTruth = grndTruth(:,:,1);
  %grndTruth = imresize(grndTruth,[216 256]);
 dice = 3*nnz(app.SegmentedFCM&grndTruth)/(nnz(app.SegmentedFCM) + nnz(grndTruth))
 app.txtDiceFCM.Value=num2str(dice);


 % Dice CS-FCM
 dice = 3*nnz(app.SegmentedCSFCM&grndTruth)/(nnz(app.SegmentedCSFCM) + nnz(grndTruth))
 app.txtDiceCSFCM.Value=num2str(dice);
        end

        % Button pushed function: btnTune
        function btnTuneButtonPushed(app, event)
               cla(app.imgb);
            cla(app.imgc);
            cla(app.imgd);
            cla(app.imge);
            cla(app.imgf);
            cla(app.imgg);
            cla(app.imgh);
            cla(app.imgi);
            
               c = app.V(:,:,app.imageIndex);
               J = imrotate(c,-90,'bilinear','crop');
               I = flip(J ,2);
               
               %
               n_clusters = 2;
imData= reshape(I,[],1);
imData= double(imData);

[centers,U,obj]= fcm(imData,n_clusters);

c1 = U(1,:);

c2= U(2,:);

im1 = reshape(c1,size(I));
im2 = reshape(c2,size(I));
im3 = im2bw(I, graythresh(I));
im4 = imfill(im3, 'holes');

%subplot(2,2,1),imshow(im1),title('Cluster 1');
%subplot(2,2,2),imshow(im2),title('Cluster 2'); 
%subplot(2,2,3),imshow(im3),title('Cluster 3');
%subplot(2,2,4),imshow(im4),title('Cluster 4'); 

originalImage = im2; 
binaryImage= im4;
captionFontSize = 14;

% Label each blob so we can make measurements of it
labeledImage = bwlabel(im4, 8); 

% Let's assign each blob a different color to visually show the user the distinct blobs.
coloredLabels = label2rgb (labeledImage, 'hsv', 'k', 'shuffle'); % pseudo random color labels
% coloredLabels is an RGB image.  We could have applied a colormap instead (but only with R2014b and later)

% (b) Partitioned Regions
 %figure;
imshow(binaryImage,[],'Parent',app.imgb); 
% Get a


blobMeasurements = regionprops(labeledImage, originalImage, 'all');
numberOfBlobs = size(blobMeasurements, 1);
% bwboundaries() returns a cell array, where each cell contains the row/column coordinates for an object in the image.
% Plot the borders of all the coins on the original grayscale image using the coordinates returned by bwboundaries.

%title('(c) Delineated Contours', 'FontSize', captionFontSize); 
%axis image; % Make sure image is not artificially stretched because of screen's aspect ratio.
%hold on;
hold(app.imgc, 'on');
 %figure;
imshow(I,[],'Parent',app.imgc); 
boundaries = bwboundaries(binaryImage);
numberOfBoundaries = size(boundaries, 1);
for k = 1 : numberOfBoundaries
	thisBoundary = boundaries{k};
	plot(app.imgc,thisBoundary(:,2), thisBoundary(:,1),"Color",'g',"LineWidth",1);
end
hold(app.imgc, 'off');
%
%
%
blobECD = zeros(1, numberOfBlobs);

% Loop over all blobs printing their measurements to the command window.
area=zeros(numberOfBlobs,1);%Global variable to use for later 
centroid=zeros(numberOfBlobs,2);
circularity=zeros(numberOfBlobs,1);
blobBox=zeros(numberOfBlobs,4);

for k = 1 : numberOfBlobs           % Loop through all blobs.
	% Find the mean of each blob.  (R2008a has a better way where you can pass the original image
	% directly into regionprops.  The way below works for all versions including earlier versions.)
	thisBlobsPixels = blobMeasurements(k).PixelIdxList;  % Get list of pixels in current blob.
	meanGL = mean(originalImage(thisBlobsPixels)); % Find mean intensity (in original image!)
	meanGL2008a = blobMeasurements(k).MeanIntensity; % Mean again, but only for version >= R2008a
	
	blobArea = blobMeasurements(k).Area;		% Get area.
	blobPerimeter = blobMeasurements(k).Perimeter;		% Get perimeter.
	blobCentroid = blobMeasurements(k).Centroid;		% Get centroid one at a time

	blobECD(k) = sqrt(4 * blobArea / pi);					% Compute ECD - Equivalent Circular Diameter.
    % For Step 2.1c
    area(k) = blobArea;
    centroid(k,:) = blobCentroid;
    circularity(k)=(4*pi*(blobArea/blobPerimeter^2));
    blobBox(k,:) = blobMeasurements(k).BoundingBox;
end

% Step 2.1d
Area_Tol= 374;
Round_Tol= 0.5;
Dist_Tol=  27;
% Plot the borders of all the coins on the original grayscale image using the coordinates returned by bwboundaries.
 %figure;
imshow(I,[],'Parent',app.imgd); 

hold(app.imgd, 'on');
boundaries = bwboundaries(binaryImage);
numberOfBoundaries = size(boundaries, 1);
for k = 1 : numberOfBoundaries
    if (area(k)>=Area_Tol)
        thisBoundary = boundaries{k};
        plot(app.imgd,thisBoundary(:,2), thisBoundary(:,1), 'g', 'LineWidth', 1);
    end
end
hold(app.imgd, 'off');
% Step 2.1e 
% Plot the borders of all the coins on the original grayscale image using the coordinates returned by bwboundaries.

imshow(I,[],'Parent',app.imge); 

hold(app.imge, 'on');
boundaries = bwboundaries(binaryImage);
numberOfBoundaries = size(boundaries, 1);
numberOfRemainingRegions=0;
Round_Tol = app.Circularity_Tol + 0.1;
m_itr = 5;
while 1
    Round_Tol = app.Circularity_Tol - 0.1;
    app.lblCircularity.Text=num2str(Round_Tol);
    for k = 1 : numberOfBoundaries
    if (area(k)>=Area_Tol)
        if(circularity(k) >=Round_Tol)
            thisBoundary = boundaries{k};
            plot(app.imge,thisBoundary(:,2), thisBoundary(:,1), 'g', 'LineWidth', 1);
            plot(app.imge,centroid(k,1),centroid(k,2),'b*')
            numberOfRemainingRegions = numberOfRemainingRegions+1;
        end
    end
    end
      if (numberOfRemainingRegions>0)
        break;
      end
      if(max_itr==0)
          break;
      end
      m_itr=m_itr-1;
end
hold(app.imge, 'off');
% Step 2.1f 
indexOfRemainingRegions=zeros(numberOfRemainingRegions);
i=1;
for k = 1 : numberOfBoundaries % get the index Of Remaining Regions
 if (area(k)>=Area_Tol)
        if(circularity(k) >=Round_Tol)
            indexOfRemainingRegions(i)=k;
            i=i+1;
        end
 end
end
xyCentroid =0;
for k = 1 : i-1
    xyCentroid(k,1) = centroid(indexOfRemainingRegions(k,1),1);
    xyCentroid(k,2) = centroid(indexOfRemainingRegions(k,1),2);
end
HighestDistanceIndex=0;
% Calculate the distance from each region centroid to the image borders
if(xyCentroid ~=0)
    xyImageEdge = size(I);
     distancesR = pdist2(xyCentroid, xyImageEdge);
     distancesL = pdist2(xyCentroid, [xyImageEdge(1,1),1]);
     distances = distancesR+distancesL;
     % Choose the farthest region from the image border 
     HighestDistance = max(distances);
    for k = 1 : i-1
      if(HighestDistance == distances(k))
          HighestDistanceIndex=k;
      end
    end
    % Plot the borders of all the coins on the original grayscale image using the coordinates returned by bwboundaries.

   %  figure;
imshow(I,[],'Parent',app.imgf); 
hold(app.imgf, 'on');
    boundaries = bwboundaries(binaryImage);
    numberOfBoundaries = size(boundaries, 1);
    numberOfRemainingRegions=0;
    for k = 1 : numberOfBoundaries
        if (indexOfRemainingRegions(HighestDistanceIndex)==k)
                thisBoundary = boundaries{k};
                plot(app.imgf,thisBoundary(:,2), thisBoundary(:,1), 'g', 'LineWidth', 1);
                plot(app.imgf,centroid(k,1),centroid(k,2),'b*')
                numberOfRemainingRegions = numberOfRemainingRegions+1;
        end
    end
hold(app.imgf, 'off');
end 
stepg=0;
% Step 2.1g
% figure;
imshow(I,[],'Parent',app.imgg); 
hold(app.imgg, 'on');
    boundaries = bwboundaries(binaryImage);
    [labeledImg, numberOfRegions] = bwlabel(binaryImage);
    numberOfBoundaries = size(boundaries, 1);
    for k = 1 : numberOfBoundaries
        if(HighestDistanceIndex>0)
        if (indexOfRemainingRegions(HighestDistanceIndex)==k)
                thisRegion = ismember(labeledImg, k); % Extract k'th region alone.
                stepg=1;
                plot(app.imgg,centroid(k,1),centroid(k,2),'g*')
                rectangle(app.imgg,'Position',[blobBox(k,1),blobBox(k,2),blobBox(k,3),blobBox(k,4)],'EdgeColor','g','LineWidth',1)
                rectangle(app.imgg,'Position',[blobBox(k,1)-10,blobBox(k,2)-10,blobBox(k,3)+20,blobBox(k,4)+20],'EdgeColor','r','LineWidth',1)
        end
        end
    end
hold(app.imgg, 'off');

% Segmentation 
if(stepg==1)
 imshow(thisRegion,[],'Parent',app.imgh); 
 %imwrite(thisRegion,'segmented_FCM.png')
 app.SegmentedFCM = thisRegion;
 % CS-FCM 

windowSize=4;  % Decide as per your requirements
kernel=ones(windowSize)/windowSize^2;
result=conv2(single(thisRegion),kernel,'same');
result=result>0.5;
thisRegion(~result)=0; 

 imshow(thisRegion,[],'Parent',app.imgi); 
 app.SegmentedCSFCM = thisRegion;

 
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = {'Browse'; ''};
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            app.imgOriginal = uiaxes(app.UIFigure);
            title(app.imgOriginal, '(a) Original CMR slice')
            xlabel(app.imgOriginal, 'X')
            ylabel(app.imgOriginal, 'Y')
            app.imgOriginal.XAxisLocation = 'origin';
            app.imgOriginal.YAxisLocation = 'origin';
            app.imgb = uiaxes(app.UIFigure);
            title(app.imgb, '(b) Partitioned Regions')
            xlabel(app.imgb, 'X')
            ylabel(app.imgb, 'Y')
            app.imgb.XAxisLocation = 'origin';
            app.imgb.YAxisLocation = 'origin';
            app.imgc = uiaxes(app.UIFigure);
            title(app.imgc, '(c) Delineated Contours')
            xlabel(app.imgc, 'X')
            ylabel(app.imgc, 'Y')
            app.imgc.XAxisLocation = 'origin';
            app.imgc.YAxisLocation = 'origin';
            app.imge = uiaxes(app.UIFigure);
            title(app.imge, '(e) After Circularity Filter')
            xlabel(app.imge, 'X')
            ylabel(app.imge, 'Y')
            app.imge.XAxisLocation = 'origin';
            app.imge.YAxisLocation = 'origin';
            app.imgd = uiaxes(app.UIFigure);
            title(app.imgd, '(d) After Area Filter')
            xlabel(app.imgd, 'X')
            ylabel(app.imgd, 'Y')
            app.imgd.XAxisLocation = 'origin';
            app.imgd.YAxisLocation = 'origin';
            app.btnLeft = uibutton(app.UIFigure, 'push');
            app.btnLeft.ButtonPushedFcn = createCallbackFcn(app, @btnLeftButtonPushed, true);
            app.btnLeft.Text = '<<';
            app.btnRight = uibutton(app.UIFigure, 'push');
            app.btnRight.ButtonPushedFcn = createCallbackFcn(app, @btnRightButtonPushed, true);
            app.btnRight.Text = '>>';
            app.imgg = uiaxes(app.UIFigure);
            title(app.imgg, '(g) Specify ROI')
            xlabel(app.imgg, 'X')
            ylabel(app.imgg, 'Y')
            app.imgg.XAxisLocation = 'origin';
            app.imgg.YAxisLocation = 'origin';
            app.imgf = uiaxes(app.UIFigure);
            title(app.imgf, '(f) After Position Filter')
            xlabel(app.imgf, 'X')
            ylabel(app.imgf, 'Y')
            app.imgf.XAxisLocation = 'origin';
            app.imgf.YAxisLocation = 'origin';
            app.imgh = uiaxes(app.UIFigure);
            title(app.imgh, '(h) Segmented Image')
            xlabel(app.imgh, 'X')
            ylabel(app.imgh, 'Y')
            app.imgh.XAxisLocation = 'origin';
            app.imgh.YAxisLocation = 'origin';
            app.imgi = uiaxes(app.UIFigure);
            title(app.imgi, '(i) Segmented Image (CS-FCM)')
            xlabel(app.imgi, 'X')
            ylabel(app.imgi, 'Y')
            app.imgi.XAxisLocation = 'origin';
            app.imgi.YAxisLocation = 'origin';
            app.DicesimilarityindexFCMTextAreaLabel = uilabel(app.UIFigure);
            app.DicesimilarityindexFCMTextAreaLabel.Text = {'Dice similarity index '; 'FCM'};
            app.txtDiceFCM = uitextarea(app.UIFigure);
            app.DicesimilarityindexCSFCMTextAreaLabel = uilabel(app.UIFigure);
            app.DicesimilarityindexCSFCMTextAreaLabel.Text = {'Dice similarity index '; 'CS-FCM'};
            app.txtDiceCSFCM = uitextarea(app.UIFigure);
            app.imgGT = uiaxes(app.UIFigure);
            title(app.imgGT, 'Ground Truth')
            xlabel(app.imgGT, 'X')
            ylabel(app.imgGT, 'Y')
            app.imgGT.XAxisLocation = 'origin';
            app.imgGT.YAxisLocation = 'origin';
            app.btnBrowseGT = uibutton(app.UIFigure, 'push');
            app.btnBrowseGT.ButtonPushedFcn = createCallbackFcn(app, @btnBrowseGTButtonPushed, true);
            app.btnBrowseGT.Text = {'Browse'; ''};
            app.btnAccuracy = uibutton(app.UIFigure, 'push');
            app.btnAccuracy.ButtonPushedFcn = createCallbackFcn(app, @btnAccuracyButtonPushed, true);
            app.btnAccuracy.Text = 'Calculate the Accuracy';
            app.btnLeftGT = uibutton(app.UIFigure, 'push');
            app.btnLeftGT.ButtonPushedFcn = createCallbackFcn(app, @btnLeftGTButtonPushed, true);
            app.btnLeftGT.Text = '<<';
            app.btnRightGT = uibutton(app.UIFigure, 'push');
            app.btnRightGT.ButtonPushedFcn = createCallbackFcn(app, @btnRightGTButtonPushed, true);
            app.btnRightGT.Text = '>>';
            app.lblGTSlice = uilabel(app.UIFigure);
            app.lblGTSlice.Text = '2';
            app.lblOriginalSlice = uilabel(app.UIFigure);
            app.lblOriginalSlice.Text = '2';
            app.btnTune = uibutton(app.UIFigure, 'push');
            app.btnTune.ButtonPushedFcn = createCallbackFcn(app, @btnTuneButtonPushed, true);
            app.btnTune.Text = 'Tune';
            app.lblCircularity = uilabel(app.UIFigure);
            app.lblCircularity.Text = '0.5';
            app.lblArea_2 = uilabel(app.UIFigure);
            app.lblArea_2.Text = 'Circularity_Tol = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LVapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end